classdef mainApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        SimulationMenu               matlab.ui.container.Menu
        NewsimulationMenu            matlab.ui.container.Menu
        SavesimulationMenu           matlab.ui.container.Menu
        LoadsimulationMenu           matlab.ui.container.Menu
        RequestsMenu_2               matlab.ui.container.Menu
        ListMenu                     matlab.ui.container.Menu
        LoadMenu                     matlab.ui.container.Menu
        VisualizeMenu                matlab.ui.container.Menu
        DepotsMenu                   matlab.ui.container.Menu
        ListMenu_2                   matlab.ui.container.Menu
        GridLayout                   matlab.ui.container.GridLayout
        Image                        matlab.ui.control.Image
        GridLayout3                  matlab.ui.container.GridLayout
        SimulateButton               matlab.ui.control.Button
        SolverPanel                  matlab.ui.container.Panel
        DropDown                     matlab.ui.control.DropDown
        DepotsvehiclesPanel          matlab.ui.container.Panel
        VehiclesEditField            matlab.ui.control.NumericEditField
        VehiclesEditFieldLabel       matlab.ui.control.Label
        DepotsEditField              matlab.ui.control.NumericEditField
        DepotsEditFieldLabel         matlab.ui.control.Label
        AddnewButton_2               matlab.ui.control.Button
        RequestsPanel                matlab.ui.container.Panel
        LastdropoffEditField         matlab.ui.control.NumericEditField
        LastdropoffEditFieldLabel    matlab.ui.control.Label
        FirstpickupEditField         matlab.ui.control.NumericEditField
        FirstpickupEditFieldLabel    matlab.ui.control.Label
        TotalrequestsEditField       matlab.ui.control.NumericEditField
        TotalrequestsEditFieldLabel  matlab.ui.control.Label
        AddnewButton                 matlab.ui.control.Button
        MapsidelengthPanel           matlab.ui.container.Panel
        Spinner                      matlab.ui.control.Spinner
    end

    
    properties (Access = private)
        
        uiState; %State of the UI. 1 - basic, 2 - add request, 3 - add depot
        
        graph; 
        coordsNodesImg;
        coordsArcsImg;
        
        img; % reference to the image that is displayed in the main Image component
        resolution; % global parameter
        padding; % global parameter
        nodeRadius; % radius of all the nodes in the images
          
        requestsExist; % indicates that the simulation contains requests
        requests; % array of requests
        
        depotsExist; % indicates that the simulation contains depots
        depots; % array of depots
        
        vehiclesExist; % indicates that the simulation contains vehicles
        vehicles; % array of vehicles
        
        solver;
    end
    
    methods (Access = private)           
        function updateImg(app)
            %1. Create emptyn white image
            app.img = uint8(ones(app.resolution,app.resolution,3)*255);
            
            %2. Draw all lines
            for i = 1:app.graph.nArcs
                app.img = drawLine(app.img, ...
                                  app.coordsArcsImg(i,1), ...
                                  app.coordsArcsImg(i,2), ...
                                  app.coordsArcsImg(i,3), ...
                                  app.coordsArcsImg(i,4), ...
                                  [0,0,0], ...
                                  3);
            end
            
            %3. Draw all nodes
            for i = 1:app.graph.nNodes
                app.img = drawDot(app.img, ...
                                  app.coordsNodesImg(i,1), ...
                                  app.coordsNodesImg(i,2), ...
                                  app.nodeRadius, ...
                                  [0,0,0], ...
                                  1, ...
                                  [0,0,0], ...
                                  -1);
            end
            
            %4. Modify request start and end points
            
            %5. Modify depot nodes
            
            %Update the image
            app.Image.ImageSource = app.img;
        end
        
        function convertToImgCoords(app)
            max_x = max(app.graph.nodeCoords(:,1));
            max_y = max(app.graph.nodeCoords(:,1));
            
            % The padding
            offset = app.padding;
            % Scaling value along the x axis
            multip_x = (app.resolution - 2 * app.padding)/max_x;
            % Scaling value along the y axis
            multip_y = (app.resolution - 2 * app.padding)/max_y;
            
            %Coordinates of nodes
            app.coordsNodesImg = zeros(app.graph.nNodes,2);
            for i = 1:app.graph.nNodes
                app.coordsNodesImg(i,1) = round(offset + multip_x * app.graph.nodeCoords(i,1));
                app.coordsNodesImg(i,2) = round(offset + multip_y * app.graph.nodeCoords(i,2));
            end
            
            % Init the global radius parameter
            app.nodeRadius = floor((multip_x+multip_y)/20);            
            if(app.nodeRadius > app.padding)
               app.nodeRadius = app.padding - 1;
            end
            
            %Start and end coordinates of arcs
            app.coordsArcsImg = zeros(app.graph.nNodes,4);
            for i = 1:app.graph.nArcs
                app.coordsArcsImg(i,1) = round(offset + multip_x * app.graph.arcCoords(i,1));
                app.coordsArcsImg(i,2) = round(offset + multip_y * app.graph.arcCoords(i,2));
                app.coordsArcsImg(i,3) = round(offset + multip_x * app.graph.arcCoords(i,3));
                app.coordsArcsImg(i,4) = round(offset + multip_y * app.graph.arcCoords(i,4));
            end
        end
        
        function handleImgClick(app, coordX, coordY)
            switch(app.uiState)
                case 1 % STATE_BASIC
                    
                case 2 % STATE_ADD_REQUEST
                    nodeId = app.findNodeByCoord(coordX, coordY)
                case 3 % STATE_ADD_DEPOT
                    
            end
        end
        
        function id = findNodeByCoord(app, coordXIn, coordYIn)
            % Returns the id of the node based on the coordinates
            id = -1;
            for i = 1:app.graph.nNodes
                if(app.checkClickInNode(i,coordXIn, coordYIn))
                    id = i;
                end
            end
        end
        
        function isIn = checkClickInNode(app, nodeId, coordXIn, coordYIn)
            xNodeImg = app.coordsNodesImg(nodeId, 1);
            yNodeImg = app.coordsNodesImg(nodeId, 2);
            if((xNodeImg - coordXIn)^2 + (yNodeImg - coordYIn)^2 <= app.nodeRadius^2)
                isIn = true;
            else
                isIn = false;
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Instantiate a darpGraph with the given sideLength       
            app.graph = darpSquareGridGraph(app.Spinner.Value);
            % Init the image properties
            app.resolution = 2048;
            app.padding = 50;
            %Calculate the image coordinates
            app.convertToImgCoords();
            
            % Initialize the status flags
            app.requestsExist = false;
            app.depotsExist = false;
            app.vehiclesExist = false;
            
            %Update the main image
            app.updateImg();
            
            %Set UI state
            app.uiState = 1;
        end

        % Value changed function: Spinner
        function SpinnerValueChanged(app, event)
            %Update the grap
            app.graph = darpSquareGridGraph(app.Spinner.Value);
            %Update the image coordinates
            app.convertToImgCoords();
            %Update the main image
            app.updateImg();
        end

        % Window button up function: UIFigure
        function UIFigureWindowButtonUp(app, event)
           % Get the position of the image
            imgX = app.Image.Position(1);
            imgY = app.Image.Position(2);
            imgWidth = app.Image.Position(3);
            imgHeight = app.Image.Position(4);
  
            %The global coordinates of the click
            clickCoords = event.Source.CurrentPoint;
            
            % Handle rectangleness
            if(imgWidth > imgHeight)
                imgX = imgX + (imgWidth - imgHeight) / 2;
                %Check if the click occured inside the image
                if(clickCoords(1) > imgX && clickCoords(2) > imgY && ...
                    clickCoords(1) < imgX + imgHeight && ...
                    clickCoords(2) < imgY + imgHeight)
                    
                    clickXImg = floor((imgY + imgHeight - clickCoords(2))/imgHeight*app.resolution);
                    clickYImg = floor((clickCoords(1) - imgX)/imgHeight*app.resolution);
                    
                    fprintf("Img click: X,Y = %d,%d\n", clickXImg, clickYImg)
                    app.handleImgClick(clickXImg, clickYImg) 
                end
            else
                imgY = imgY + (imgHeight - imgWidth) / 2;  
                %Check if the click occured inside the image
                if(clickCoords(1) > imgX && clickCoords(2) > imgY && ...
                    clickCoords(1) < imgX + imgWidth && ...
                    clickCoords(2) < imgY + imgWidth)
                    
                    clickXImg = floor((imgY + imgWidth - clickCoords(2))/imgWidth*app.resolution);
                    clickYImg = floor((clickCoords(1) - imgX)/imgWidth*app.resolution);
                    
                    fprintf("Img click: X,Y = %d,%d\n", clickXImg, clickYImg)
                    app.handleImgClick(clickXImg, clickYImg)           
                end
            end
            
        end

        % Button pushed function: AddnewButton
        function AddnewButtonPushed(app, event)
            app.uiState = 2; %STATE_ADD_REQUEST
            app.RequestsPanel.BackgroundColor = [1,0,0];
            app.RequestsPanel.FontWeight = 'bold';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowButtonUpFcn = createCallbackFcn(app, @UIFigureWindowButtonUp, true);
            app.SimulationMenu = uimenu(app.UIFigure);
            app.SimulationMenu.Text = 'Simulation';
            app.NewsimulationMenu = uimenu(app.SimulationMenu);
            app.NewsimulationMenu.Text = 'New simulation';
            app.SavesimulationMenu = uimenu(app.SimulationMenu);
            app.SavesimulationMenu.Text = 'Save simulation';
            app.LoadsimulationMenu = uimenu(app.SimulationMenu);
            app.LoadsimulationMenu.Text = 'Load simulation';
            app.RequestsMenu_2 = uimenu(app.UIFigure);
            app.RequestsMenu_2.Text = 'Requests';
            app.ListMenu = uimenu(app.RequestsMenu_2);
            app.ListMenu.Text = 'List';
            app.LoadMenu = uimenu(app.RequestsMenu_2);
            app.LoadMenu.Text = 'Load';
            app.VisualizeMenu = uimenu(app.RequestsMenu_2);
            app.VisualizeMenu.Text = 'Visualize';
            app.DepotsMenu = uimenu(app.UIFigure);
            app.DepotsMenu.Text = 'Depots';
            app.ListMenu_2 = uimenu(app.DepotsMenu);
            app.ListMenu_2.Text = 'List';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.MapsidelengthPanel = uipanel(app.GridLayout3);
            app.MapsidelengthPanel.Title = 'Map side length';
            app.Spinner = uispinner(app.MapsidelengthPanel);
            app.Spinner.Limits = [3 10];
            app.Spinner.RoundFractionalValues = 'on';
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.Spinner.Value = 6;
            app.RequestsPanel = uipanel(app.GridLayout3);
            app.RequestsPanel.Title = 'Requests';
            app.AddnewButton = uibutton(app.RequestsPanel, 'push');
            app.AddnewButton.ButtonPushedFcn = createCallbackFcn(app, @AddnewButtonPushed, true);
            app.AddnewButton.Text = 'Add new';
            app.TotalrequestsEditFieldLabel = uilabel(app.RequestsPanel);
            app.TotalrequestsEditFieldLabel.Text = 'Total requests';
            app.TotalrequestsEditField = uieditfield(app.RequestsPanel, 'numeric');
            app.FirstpickupEditFieldLabel = uilabel(app.RequestsPanel);
            app.FirstpickupEditFieldLabel.Text = 'First pickup';
            app.FirstpickupEditField = uieditfield(app.RequestsPanel, 'numeric');
            app.LastdropoffEditFieldLabel = uilabel(app.RequestsPanel);
            app.LastdropoffEditFieldLabel.Text = 'Last dropoff';
            app.LastdropoffEditField = uieditfield(app.RequestsPanel, 'numeric');
            app.DepotsvehiclesPanel = uipanel(app.GridLayout3);
            app.DepotsvehiclesPanel.Title = 'Depots & vehicles';
            app.AddnewButton_2 = uibutton(app.DepotsvehiclesPanel, 'push');
            app.AddnewButton_2.Text = 'Add new';
            app.DepotsEditFieldLabel = uilabel(app.DepotsvehiclesPanel);
            app.DepotsEditFieldLabel.Text = 'Depots';
            app.DepotsEditField = uieditfield(app.DepotsvehiclesPanel, 'numeric');
            app.VehiclesEditFieldLabel = uilabel(app.DepotsvehiclesPanel);
            app.VehiclesEditFieldLabel.Text = 'Vehicles';
            app.VehiclesEditField = uieditfield(app.DepotsvehiclesPanel, 'numeric');
            app.SolverPanel = uipanel(app.GridLayout3);
            app.SolverPanel.Title = 'Solver';
            app.DropDown = uidropdown(app.SolverPanel);
            app.SimulateButton = uibutton(app.GridLayout3, 'push');
            app.SimulateButton.Text = 'Simulate';
            app.Image = uiimage(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mainApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end